classdef getcropping_ScXY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        XMinSpinnerLabel       matlab.ui.control.Label
        XMinSpinner            matlab.ui.control.Spinner
        XMaxSpinnerLabel       matlab.ui.control.Label
        XMaxSpinner            matlab.ui.control.Spinner
        ListBox                matlab.ui.control.ListBox
        RefreshButton          matlab.ui.control.Button
        LinenumSpinnerLabel    matlab.ui.control.Label
        LinenumSpinner         matlab.ui.control.Spinner
        FlyBackXCroppingLabel  matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        DebugButton            matlab.ui.control.Button
        CopycalibratedButton   matlab.ui.control.Button
        CopyPixellocsButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        data % Description
        ScX % Description
        ScY % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshButton
        function refresh(app, event)
            
global hDATA
app.data=struct(hDATA)
clear hDATA
app.ListBox.Items={app.data.data.dataname}.';
app.ListBox.ItemsData=transpose(linspace(1,size(app.ListBox.Items,2),size(app.ListBox.Items,2)));
        end

        % Value changed function: ListBox
        function displayx(app, event)
        app.ScX=app.data.data(app.ListBox.Value).dataval(4,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.ScY=app.data.data(app.ListBox.Value).dataval(5,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.XMaxSpinner.Limits=[1 size(app.data.data(app.ListBox.Value).dataval,2)]  
        app.XMinSpinner.Limits=[1 size(app.data.data(app.ListBox.Value).dataval,2)]  
        app.LinenumSpinner.Limits=[1 size(app.data.data(app.ListBox.Value).dataval,5)] 
        scatter(app.UIAxes,app.ScX,app.ScY)
        dX=app.data.data(app.ListBox.Value).datainfo.dX
        app.EditField.Value=sprintf('%d    %d',app.XMinSpinner.Value*dX,app.XMaxSpinner.Value*dX)
        end

        % Callback function
        function changeline(app, event)
            app.ScX=app.data.data(app.ListBox.Value).dataval(4,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.ScY=app.data.data(app.ListBox.Value).dataval(5,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        scatter(app.UIAxes,app.ScX,app.ScY)
        dX=app.data.data(app.ListBox.Value).datainfo.dX
        app.EditField.Value=sprintf('%d    %d',app.XMinSpinner.Value*dX,app.XMaxSpinner.Value*dX)
        end

        % Value changed function: XMinSpinner
        function xminset(app, event)
        app.ScX=app.data.data(app.ListBox.Value).dataval(4,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.ScY=app.data.data(app.ListBox.Value).dataval(5,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        scatter(app.UIAxes,app.ScX,app.ScY)
        dX=app.data.data(app.ListBox.Value).datainfo.dX
        app.EditField.Value=sprintf('%d    %d',app.XMinSpinner.Value*dX,app.XMaxSpinner.Value*dX)
        end

        % Value changed function: XMaxSpinner
        function xmaxset(app, event)
        app.ScX=app.data.data(app.ListBox.Value).dataval(4,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.ScY=app.data.data(app.ListBox.Value).dataval(5,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        scatter(app.UIAxes,app.ScX,app.ScY)
        dX=app.data.data(app.ListBox.Value).datainfo.dX
        app.EditField.Value=sprintf('%d    %d',app.XMinSpinner.Value*dX,app.XMaxSpinner.Value*dX)
        end

        % Value changed function: LinenumSpinner
        function linenum(app, event)
            value= app.LinenumSpinner.Value;
            app.ScX=app.data.data(app.ListBox.Value).dataval(4,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        app.ScY=app.data.data(app.ListBox.Value).dataval(5,[app.XMinSpinner.Value:app.XMaxSpinner.Value],:,:,app.LinenumSpinner.Value)
        scatter(app.UIAxes,app.ScX,app.ScY)
        dX=app.data.data(app.ListBox.Value).datainfo.dX
        app.EditField.Value=sprintf('%d    %d',app.XMinSpinner.Value*dX,app.XMaxSpinner.Value*dX)
        
        end

        % Button pushed function: DebugButton
        function test(app, event)
            'Hello'
            
        end

        % Button pushed function: CopycalibratedButton
        function Copy_Cal(app, event)
         clipboard('copy',app.EditField.Value)
        end

        % Button pushed function: CopyPixellocsButton
        function copy_pix(app, event)
        test=sprintf('%d     %d',app.XMinSpinner.Value,app.XMaxSpinner.Value)
        clipboard('copy',test)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XMinSpinnerLabel = uilabel(app.UIFigure);
            app.XMinSpinnerLabel.Text = 'XMin';
            app.XMinSpinner = uispinner(app.UIFigure);
            app.XMinSpinner.Limits = [1 Inf];
            app.XMinSpinner.ValueChangedFcn = createCallbackFcn(app, @xminset, true);
            app.XMinSpinner.Value = 1;
            app.XMaxSpinnerLabel = uilabel(app.UIFigure);
            app.XMaxSpinnerLabel.Text = 'XMax';
            app.XMaxSpinner = uispinner(app.UIFigure);
            app.XMaxSpinner.Limits = [1 Inf];
            app.XMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @xmaxset, true);
            app.XMaxSpinner.Value = 1;
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @displayx, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @refresh, true);
            app.RefreshButton.Text = 'Refresh';
            app.LinenumSpinnerLabel = uilabel(app.UIFigure);
            app.LinenumSpinnerLabel.Text = 'Linenum';
            app.LinenumSpinner = uispinner(app.UIFigure);
            app.LinenumSpinner.ValueChangedFcn = createCallbackFcn(app, @linenum, true);
            app.LinenumSpinner.Value = 1;
            app.FlyBackXCroppingLabel = uilabel(app.UIFigure);
            app.FlyBackXCroppingLabel.Text = 'Fly Back X Cropping';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.DebugButton = uibutton(app.UIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @test, true);
            app.DebugButton.Text = 'Debug';
            app.CopycalibratedButton = uibutton(app.UIFigure, 'push');
            app.CopycalibratedButton.ButtonPushedFcn = createCallbackFcn(app, @Copy_Cal, true);
            app.CopycalibratedButton.Text = 'Copy calibrated';
            app.CopyPixellocsButton = uibutton(app.UIFigure, 'push');
            app.CopyPixellocsButton.ButtonPushedFcn = createCallbackFcn(app, @copy_pix, true);
            app.CopyPixellocsButton.Text = 'Copy Pixel locs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getcropping_ScXY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end